classdef Simulation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Diagramm33Tab                  matlab.ui.container.Tab
        Startgeschwindigkeitv0EditFieldLabel  matlab.ui.control.Label
        Startgeschwindigkeitv0EditField  matlab.ui.control.NumericEditField
        kmhLabel                       matlab.ui.control.Label
        Simulation33StartenButton      matlab.ui.control.Button
        TabGroup3                      matlab.ui.container.TabGroup
        BeschleunigungTab              matlab.ui.container.Tab
        UIAxes                         matlab.ui.control.UIAxes
        GeschwindigkeitTab             matlab.ui.container.Tab
        UIAxes2                        matlab.ui.control.UIAxes
        StreckeTab                     matlab.ui.container.Tab
        UIAxes3                        matlab.ui.control.UIAxes
        Diagramm37Tab                  matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        PsiEditFieldLabel              matlab.ui.control.Label
        PsiEditField                   matlab.ui.control.NumericEditField
        ChiEditFieldLabel              matlab.ui.control.Label
        ChiEditField                   matlab.ui.control.NumericEditField
        Simulation37StartenButton      matlab.ui.control.Button
        Diagramm46Tab                  matlab.ui.container.Tab
        TabGroup4                      matlab.ui.container.TabGroup
        KonstanteGeschwindigkeitTab    matlab.ui.container.Tab
        GeschwindigkeitEditFieldLabel  matlab.ui.control.Label
        GeschwindigkeitEditField       matlab.ui.control.NumericEditField
        msLabel                        matlab.ui.control.Label
        SimulationstartenButton        matlab.ui.control.Button
        UIAxes5                        matlab.ui.control.UIAxes
        KonstanterRadiusTab            matlab.ui.container.Tab
        RadiusEditFieldLabel           matlab.ui.control.Label
        RadiusEditField                matlab.ui.control.NumericEditField
        mLabel_5                       matlab.ui.control.Label
        SimulationstartenButton_2      matlab.ui.control.Button
        UIAxes6                        matlab.ui.control.UIAxes
        Diagramm48Tab                  matlab.ui.container.Tab
        UIAxes7                        matlab.ui.control.UIAxes
        SimulationstartenButton_3      matlab.ui.control.Button
        ParameterTab                   matlab.ui.container.Tab
        TabGroup2                      matlab.ui.container.TabGroup
        Fahrzeug                       matlab.ui.container.Tab
        FahrzeugmassemEditFieldLabel   matlab.ui.control.Label
        FahrzeugmassemEditField        matlab.ui.control.NumericEditField
        kgLabel                        matlab.ui.control.Label
        FahrzeuglngelEditFieldLabel    matlab.ui.control.Label
        FahrzeuglngelEditField         matlab.ui.control.NumericEditField
        mLabel                         matlab.ui.control.Label
        VorderlngelvEditFieldLabel     matlab.ui.control.Label
        VorderlngelvEditField          matlab.ui.control.NumericEditField
        mLabel_2                       matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        HinterlngelhEditFieldLabel     matlab.ui.control.Label
        HinterlngelhEditField          matlab.ui.control.NumericEditField
        FahrzeugverhaltenTab           matlab.ui.container.Tab
        CvEditFieldLabel               matlab.ui.control.Label
        CvEditField                    matlab.ui.control.NumericEditField
        ChEditFieldLabel               matlab.ui.control.Label
        ChEditField                    matlab.ui.control.NumericEditField
        CvEditField_2Label             matlab.ui.control.Label
        CvEditField_2                  matlab.ui.control.NumericEditField
        ChEditField_2Label             matlab.ui.control.Label
        ChEditField_2                  matlab.ui.control.NumericEditField
        NradLabel                      matlab.ui.control.Label
        NradLabel_2                    matlab.ui.control.Label
        radLabel                       matlab.ui.control.Label
        radLabel_2                     matlab.ui.control.Label
        SGEditFieldLabel               matlab.ui.control.Label
        SGEditField                    matlab.ui.control.NumericEditField
        EGEditFieldLabel               matlab.ui.control.Label
        EGEditField                    matlab.ui.control.NumericEditField
        rads2mLabel                    matlab.ui.control.Label
        rads2mLabel_2                  matlab.ui.control.Label
        hsEditFieldLabel               matlab.ui.control.Label
        hsEditField                    matlab.ui.control.NumericEditField
        mLabel_4                       matlab.ui.control.Label
        ThetaEditFieldLabel            matlab.ui.control.Label
        ThetaEditField                 matlab.ui.control.NumericEditField
        ErdberschleunigunggEditFieldLabel  matlab.ui.control.Label
        ErdbeschleunigungEditField     matlab.ui.control.NumericEditField
        kgm2Label                      matlab.ui.control.Label
        ms2Label                       matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function simulieren33(app)
            app.Simulation33StartenButton.Enable = "Off";
            
            load_system('Laengsdynamikmodell');
            
            params = get_param('Laengsdynamikmodell','modelworkspace');
            
            lv = app.VorderlngelvEditField.Value;
            hs = app.hsEditField.Value;
            l = app.FahrzeuglngelEditField.Value;
            g = app.ErdbeschleunigungEditField.Value;
            v0 = app.Startgeschwindigkeitv0EditField.Value / 3.6;
            
            xReifen = [0.0 0.25 1.0];
            yReifen = [0.0 1 1];
            maxYReifen = max(yReifen);
            
            params.assignin('rTableData', yReifen);
            params.assignin('rBreakpoints', xReifen);
            params.assignin('lv',lv);
            params.assignin('hs',hs);
            params.assignin('l',l);
            params.assignin('g',g);
            params.assignin('v0',v0);
            params.assignin('zTableData',[0.0 0.0 0.1 1.0 1.0] * maxYReifen);
            params.assignin('zBreakpoints',[0.0 1.5 1.95 2.15 10.0]);
            params.assignin('zv_z',1.0);
            params.assignin('zh_z',1.0);
            params.assignin('controllerActive',1);
            
            duration = int32(max([10.0 (4 + v0 / (maxYReifen * g))]));
            duration = duration - mod(duration, 2);
            
            output = sim('Laengsdynamikmodell.slx','Solver','ode4','StopTime',num2str(duration),'FixedStep','0.01');
            
            a = output.a;
            v = output.v;
            s = output.s;
            
            hold(app.UIAxes,'off');
            plot(app.UIAxes, a.Time, a.Data, 'LineWidth', 2, 'Color', [0 0 0.3]);
            hold(app.UIAxes2,'off');
            plot(app.UIAxes2, v.Time, v.Data * 3.6, 'LineWidth', 2, 'Color', [1.0 0.5 0.3]);
            hold(app.UIAxes3,'off');
            plot(app.UIAxes3, s.Time, s.Data, 'LineWidth', 2, 'Color', [0.8 0 0]);

            close_system('Laengsdynamikmodell',0);
            
            app.Simulation33StartenButton.Enable = "On";
        end
        
        function results = simulieren37(app)
            app.Simulation37StartenButton.Enable = "Off";
            
            load_system('Laengsdynamikmodell.slx');
            
            params = get_param('Laengsdynamikmodell','modelworkspace');
            
            lv = app.VorderlngelvEditField.Value;
            hs = app.hsEditField.Value;
            l = app.FahrzeuglngelEditField.Value;
            g = app.ErdbeschleunigungEditField.Value;
            v0 = app.Startgeschwindigkeitv0EditField.Value / 3.6;
            
            xReifen = [0.0 0.25 1.0];
            yReifen = [0.0 1 1];
            
            psi = lv / l;
            chi = hs / l;
            zmin = (-(1-psi)/chi);
            zmax = (psi/chi);
            zTableData = (zmin:0.01:zmax);
            num = size(zTableData);
            zBreakpoints = (1:num(2))*0.01;
            
            maxFBhValue = psi*psi / (4 * chi);
            maxFBvValue = - (1 - psi) * (1 - psi) / (4 * chi);
            
            params.assignin('rTableData', yReifen);
            params.assignin('rBreakpoints', xReifen);
            params.assignin('lv',lv);
            params.assignin('hs',hs);
            params.assignin('l',l);
            params.assignin('g',g);
            params.assignin('v0',v0);
            params.assignin('zv_z',1.0);
            params.assignin('zh_z',1.0);
            params.assignin('zTableData',zTableData);
            params.assignin('zBreakpoints',zBreakpoints);
            
            output = sim('Laengsdynamikmodell.slx','Solver','ode4','StopTime','10.0','FixedStep','0.01');
            
            FBv = output.FBv;
            FBh = output.FBh;
            
            hold(app.UIAxes4, 'off');
            plot(app.UIAxes4, FBv,FBh, 'LineWidth', 2);
            hold(app.UIAxes4, 'on');
            plot(app.UIAxes4, [0 0],[(zmin * 1.1) (maxFBhValue * 1.3)], 'LineWidth', 1.5, 'Color', [0.2 0.2 0.2]);
            plot(app.UIAxes4, [(maxFBvValue * 1.3) (zmax * 1.1)], [0 0], 'LineWidth', 1.5, 'Color', [0.2 0.2 0.2]);
            grid(app.UIAxes4, 'on');
            
            close_system('Laengsdynamikmodell',0);
            
            app.Simulation37StartenButton.Enable = "On";
        end
    end
    

    methods (Access = private)

        % Button pushed function: Simulation33StartenButton
        function Simulieren33StartenButtonPushed(app, event)
            simulieren33(app);
        end

        % Button pushed function: Simulation37StartenButton
        function Simulation37StartenButtonPushed(app, event)
            simulieren37(app);
        end

        % Button pushed function: SimulationstartenButton
        function Simulation46GeschwindigkeitStartenButtonPushed(app, event)
            Diagramm46KonstanteGeschwindigkeit(app.UIAxes5, app.GeschwindigkeitEditField.Value, app.ErdbeschleunigungEditField.Value, app.VorderlngelvEditField.Value, app.HinterlngelhEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmassemEditField.Value, app.ThetaEditField.Value)
        end

        % Button pushed function: SimulationstartenButton_2
        function Simulation46RadiusStartenButtonPushed(app, event)
            Diagramm46KonstanterRadius(app.UIAxes6, app.RadiusEditField.Value, app.ErdbeschleunigungEditField.Value, app.VorderlngelvEditField.Value, app.HinterlngelhEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmassemEditField.Value, app.ThetaEditField.Value)
        end

        % Button pushed function: SimulationstartenButton_3
        function Simulation48StartenButtonPushed(app, event)
           Diagramm48(app.UIAxes7, app.ErdbeschleunigungEditField.Value, app.VorderlngelvEditField.Value, app.HinterlngelhEditField.Value, app.EGEditField.Value, app.SGEditField.Value, 16, app.FahrzeugmassemEditField.Value, app.ThetaEditField.Value)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Diagramm33Tab = uitab(app.TabGroup);
            app.Diagramm33Tab.Title = 'Diagramm 3.3';
            app.Startgeschwindigkeitv0EditFieldLabel = uilabel(app.Diagramm33Tab);
            app.Startgeschwindigkeitv0EditFieldLabel.Text = 'Startgeschwindigkeit (v0)';
            app.Startgeschwindigkeitv0EditField = uieditfield(app.Diagramm33Tab, 'numeric');
            app.Startgeschwindigkeitv0EditField.Limits = [0 200];
            app.Startgeschwindigkeitv0EditField.Value = 90;
            app.kmhLabel = uilabel(app.Diagramm33Tab);
            app.kmhLabel.Text = 'km/h';
            app.Simulation33StartenButton = uibutton(app.Diagramm33Tab, 'push');
            app.Simulation33StartenButton.ButtonPushedFcn = createCallbackFcn(app, @Simulieren33StartenButtonPushed, true);
            app.Simulation33StartenButton.Text = 'Simulieren';
            app.TabGroup3 = uitabgroup(app.Diagramm33Tab);
            app.BeschleunigungTab = uitab(app.TabGroup3);
            app.BeschleunigungTab.Title = 'Beschleunigung';
            app.UIAxes = uiaxes(app.BeschleunigungTab);
            title(app.UIAxes, 'Beschleunigung')
            xlabel(app.UIAxes, 't (in s)')
            ylabel(app.UIAxes, 'a (in m/s^2)')
            app.GeschwindigkeitTab = uitab(app.TabGroup3);
            app.GeschwindigkeitTab.Title = 'Geschwindigkeit';
            app.UIAxes2 = uiaxes(app.GeschwindigkeitTab);
            title(app.UIAxes2, 'Geschwindigkeit')
            xlabel(app.UIAxes2, 't (in s)')
            ylabel(app.UIAxes2, 'v (in km/h)')
            app.StreckeTab = uitab(app.TabGroup3);
            app.StreckeTab.Title = 'Strecke';
            app.UIAxes3 = uiaxes(app.StreckeTab);
            title(app.UIAxes3, 'Strecke')
            xlabel(app.UIAxes3, 't (in s)')
            ylabel(app.UIAxes3, 's (in m)')
            app.Diagramm37Tab = uitab(app.TabGroup);
            app.Diagramm37Tab.Title = 'Diagramm 3.7';
            app.UIAxes4 = uiaxes(app.Diagramm37Tab);
            title(app.UIAxes4, 'Tangentialkraftverteilung')
            xlabel(app.UIAxes4, 'FBv/Gg')
            ylabel(app.UIAxes4, 'FBh/Gg')
            app.PsiEditFieldLabel = uilabel(app.Diagramm37Tab);
            app.PsiEditFieldLabel.Text = 'Psi';
            app.PsiEditField = uieditfield(app.Diagramm37Tab, 'numeric');
            app.PsiEditField.Value = 0.48;
            app.ChiEditFieldLabel = uilabel(app.Diagramm37Tab);
            app.ChiEditFieldLabel.Text = 'Chi';
            app.ChiEditField = uieditfield(app.Diagramm37Tab, 'numeric');
            app.ChiEditField.Value = 0.1786;
            app.Simulation37StartenButton = uibutton(app.Diagramm37Tab, 'push');
            app.Simulation37StartenButton.ButtonPushedFcn = createCallbackFcn(app, @Simulation37StartenButtonPushed, true);
            app.Simulation37StartenButton.Text = 'Simulation starten';
            app.Diagramm46Tab = uitab(app.TabGroup);
            app.Diagramm46Tab.Title = 'Diagramm 4.6';
            app.TabGroup4 = uitabgroup(app.Diagramm46Tab);
            app.KonstanteGeschwindigkeitTab = uitab(app.TabGroup4);
            app.KonstanteGeschwindigkeitTab.Title = 'Konstante Geschwindigkeit';
            app.GeschwindigkeitEditFieldLabel = uilabel(app.KonstanteGeschwindigkeitTab);
            app.GeschwindigkeitEditFieldLabel.Text = 'Geschwindigkeit';
            app.GeschwindigkeitEditField = uieditfield(app.KonstanteGeschwindigkeitTab, 'numeric');
            app.GeschwindigkeitEditField.Limits = [0 200];
            app.GeschwindigkeitEditField.Value = 25;
            app.msLabel = uilabel(app.KonstanteGeschwindigkeitTab);
            app.msLabel.Text = 'm/s';
            app.SimulationstartenButton = uibutton(app.KonstanteGeschwindigkeitTab, 'push');
            app.SimulationstartenButton.ButtonPushedFcn = createCallbackFcn(app, @Simulation46GeschwindigkeitStartenButtonPushed, true);
            app.SimulationstartenButton.Text = 'Simulation starten';
            app.UIAxes5 = uiaxes(app.KonstanteGeschwindigkeitTab);
            title(app.UIAxes5, 'Konstante Geschwindigkeit')
            xlabel(app.UIAxes5, 'ay in [m/s^2]')
            ylabel(app.UIAxes5, 'δh/is in [rad]')
            app.KonstanterRadiusTab = uitab(app.TabGroup4);
            app.KonstanterRadiusTab.Title = 'Konstanter Radius';
            app.RadiusEditFieldLabel = uilabel(app.KonstanterRadiusTab);
            app.RadiusEditFieldLabel.Text = 'Radius';
            app.RadiusEditField = uieditfield(app.KonstanterRadiusTab, 'numeric');
            app.RadiusEditField.Limits = [25 40];
            app.RadiusEditField.Value = 25;
            app.mLabel_5 = uilabel(app.KonstanterRadiusTab);
            app.mLabel_5.Text = 'm';
            app.SimulationstartenButton_2 = uibutton(app.KonstanterRadiusTab, 'push');
            app.SimulationstartenButton_2.ButtonPushedFcn = createCallbackFcn(app, @Simulation46RadiusStartenButtonPushed, true);
            app.SimulationstartenButton_2.Text = 'Simulation starten';
            app.UIAxes6 = uiaxes(app.KonstanterRadiusTab);
            title(app.UIAxes6, 'Konstanter Radius')
            xlabel(app.UIAxes6, '[ay (in m/s^2)]')
            ylabel(app.UIAxes6, '[δh/is (in rad)]')
            app.Diagramm48Tab = uitab(app.TabGroup);
            app.Diagramm48Tab.Title = 'Diagramm 4.8';
            app.UIAxes7 = uiaxes(app.Diagramm48Tab);
            title(app.UIAxes7, 'Gierreaktion')
            xlabel(app.UIAxes7, '[v (in m/s)]')
            ylabel(app.UIAxes7, '[deltaPsi/(deltah/is)]')
            app.SimulationstartenButton_3 = uibutton(app.Diagramm48Tab, 'push');
            app.SimulationstartenButton_3.ButtonPushedFcn = createCallbackFcn(app, @Simulation48StartenButtonPushed, true);
            app.SimulationstartenButton_3.Text = 'Simulation starten';
            app.ParameterTab = uitab(app.TabGroup);
            app.ParameterTab.Title = 'Parameter';
            app.TabGroup2 = uitabgroup(app.ParameterTab);
            app.Fahrzeug = uitab(app.TabGroup2);
            app.Fahrzeug.Title = 'Fahrzeugmaße';
            app.FahrzeugmassemEditFieldLabel = uilabel(app.Fahrzeug);
            app.FahrzeugmassemEditFieldLabel.Text = 'Fahrzeugmasse (m)';
            app.FahrzeugmassemEditField = uieditfield(app.Fahrzeug, 'numeric');
            app.FahrzeugmassemEditField.Value = 1550;
            app.kgLabel = uilabel(app.Fahrzeug);
            app.kgLabel.Text = 'kg';
            app.FahrzeuglngelEditFieldLabel = uilabel(app.Fahrzeug);
            app.FahrzeuglngelEditFieldLabel.Text = 'Fahrzeuglänge (l)';
            app.FahrzeuglngelEditField = uieditfield(app.Fahrzeug, 'numeric');
            app.FahrzeuglngelEditField.Value = 2.8;
            app.mLabel = uilabel(app.Fahrzeug);
            app.mLabel.Text = 'm';
            app.VorderlngelvEditFieldLabel = uilabel(app.Fahrzeug);
            app.VorderlngelvEditFieldLabel.Text = 'Vorderlänge (lv)';
            app.VorderlngelvEditField = uieditfield(app.Fahrzeug, 'numeric');
            app.VorderlngelvEditField.Value = 1.344;
            app.mLabel_2 = uilabel(app.Fahrzeug);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.Fahrzeug);
            app.mLabel_3.Text = 'm';
            app.HinterlngelhEditFieldLabel = uilabel(app.Fahrzeug);
            app.HinterlngelhEditFieldLabel.Text = 'Hinterlänge (lh)';
            app.HinterlngelhEditField = uieditfield(app.Fahrzeug, 'numeric');
            app.HinterlngelhEditField.Value = 1.456;
            app.FahrzeugverhaltenTab = uitab(app.TabGroup2);
            app.FahrzeugverhaltenTab.Title = 'Fahrzeugverhalten';
            app.CvEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.CvEditFieldLabel.Text = 'Cv';
            app.CvEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.CvEditField.Value = 75000;
            app.ChEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.ChEditFieldLabel.Text = 'Ch';
            app.ChEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.ChEditField.Value = 150000;
            app.CvEditField_2Label = uilabel(app.FahrzeugverhaltenTab);
            app.CvEditField_2Label.Text = 'Cv*';
            app.CvEditField_2 = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.CvEditField_2.Value = 9.485;
            app.ChEditField_2Label = uilabel(app.FahrzeugverhaltenTab);
            app.ChEditField_2Label.Text = 'Ch*';
            app.ChEditField_2 = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.ChEditField_2.Value = 20.55;
            app.NradLabel = uilabel(app.FahrzeugverhaltenTab);
            app.NradLabel.Text = 'N/rad';
            app.NradLabel_2 = uilabel(app.FahrzeugverhaltenTab);
            app.NradLabel_2.Text = 'N/rad';
            app.radLabel = uilabel(app.FahrzeugverhaltenTab);
            app.radLabel.Text = '1/rad';
            app.radLabel_2 = uilabel(app.FahrzeugverhaltenTab);
            app.radLabel_2.Text = '1/rad';
            app.SGEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.SGEditFieldLabel.Text = 'SG';
            app.SGEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.SGEditField.Value = 0.00496;
            app.EGEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.EGEditFieldLabel.Text = 'EG';
            app.EGEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.EGEditField.Value = 0.005787;
            app.rads2mLabel = uilabel(app.FahrzeugverhaltenTab);
            app.rads2mLabel.Text = '(rad*s^2)/m';
            app.rads2mLabel_2 = uilabel(app.FahrzeugverhaltenTab);
            app.rads2mLabel_2.Text = '(rad*s^2)/m';
            app.hsEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.hsEditFieldLabel.Text = 'hs';
            app.hsEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.hsEditField.Value = 0.5;
            app.mLabel_4 = uilabel(app.FahrzeugverhaltenTab);
            app.mLabel_4.Text = 'm';
            app.ThetaEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.ThetaEditFieldLabel.Text = 'Theta';
            app.ThetaEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.ThetaEditField.Value = 2700;
            app.ErdberschleunigunggEditFieldLabel = uilabel(app.FahrzeugverhaltenTab);
            app.ErdberschleunigunggEditFieldLabel.Text = 'Erdberschleunigung (g)';
            app.ErdbeschleunigungEditField = uieditfield(app.FahrzeugverhaltenTab, 'numeric');
            app.ErdbeschleunigungEditField.Value = 9.81;
            app.kgm2Label = uilabel(app.FahrzeugverhaltenTab);
            app.kgm2Label.Text = 'kg*m^2';
            app.ms2Label = uilabel(app.FahrzeugverhaltenTab);
            app.ms2Label.Text = 'm/s^2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Simulation

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end